classdef mainCAO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulateurdebalanceUIFigure   matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SimulationTab                 matlab.ui.container.Tab
        UIAnim                        matlab.ui.control.UIAxes
        UIMassAction                  matlab.ui.control.UIAxes
        Lamp                          matlab.ui.control.Lamp
        ParamtresnumismatiqueLabel    matlab.ui.control.Label
        SimulerButton                 matlab.ui.control.Button
        UICoinTable                   matlab.ui.control.Table
        ConfigurationTab              matlab.ui.container.Tab
        DimensionsdelabalancePanel    matlab.ui.container.Panel
        LongueurcmEditFieldLabel      matlab.ui.control.Label
        LongueurBalanceField          matlab.ui.control.NumericEditField
        LargeurcmEditFieldLabel       matlab.ui.control.Label
        LargeurBalanceField           matlab.ui.control.NumericEditField
        HauteurcmEditFieldLabel       matlab.ui.control.Label
        HauteurBalanceField           matlab.ui.control.NumericEditField
        UIDimension                   matlab.ui.control.UIAxes
        PositionduplateauSliderLabel  matlab.ui.control.Label
        PositionduplateauSlider       matlab.ui.control.Slider
        LameflexiblePanel             matlab.ui.container.Panel
        LongueurcmEditField_2Label    matlab.ui.control.Label
        LongueurLameField             matlab.ui.control.NumericEditField
        LargeurcmEditField_2Label     matlab.ui.control.Label
        LargeurLameField              matlab.ui.control.NumericEditField
        paisseurcmEditFieldLabel      matlab.ui.control.Label
        EpaisseurLameField            matlab.ui.control.NumericEditField
        MassegEditFieldLabel          matlab.ui.control.Label
        MasseLameField                matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        CapteurcapacitifPanel         matlab.ui.container.Panel
        NombredespiresSpinnerLabel    matlab.ui.control.Label
        NombredespiresSpinner         matlab.ui.control.Spinner
        LongueurcmEditField_3Label    matlab.ui.control.Label
        LongueurCaptField             matlab.ui.control.NumericEditField
        LargeurcmEditField_3Label     matlab.ui.control.Label
        LargeurCaptField              matlab.ui.control.NumericEditField
        MatriauxDropDownLabel         matlab.ui.control.Label
        MateriauxCaptDropDown         matlab.ui.control.DropDown
        AutrePanel                    matlab.ui.container.Panel
        AmplificateurPanel            matlab.ui.container.Panel
        ActionneurlineairePanel       matlab.ui.container.Panel
        ArchiveTab                    matlab.ui.container.Tab
    end

    properties (Access = private)
        ScaleWidth  = 15; % Largeur initiale de la balance
        ScaleHeight = 5; % Hauteur initiale de la balance
        LameWidth   = 3; % Épaisseur de la lame
        LameNumel   = 100; % Nombre de points dans la lame
        LameInit    = 0.*linspace(0,15,100); % Vecteur de la lame initial
        SystemStruct = struct('nbpieces',0,'typepieces',0); % Structure vide pour les paramètres de l'utilisateur
    end
    
    methods (Access = public)
        
        function ret = updateCoinTable(app,currency,amount,quantity)
            app.UICoinTable
        end
    end
    
    methods (Access = private)
        
        function disableUI(app)
            set(app.UICoinTable, 'ColumnEditable', false);
            set(app.DimensionsdelabalancePanel, 'Visible', false);
            set(app.LameflexiblePanel.Children, 'Visible', false);
            set(app.CapteurcapacitifPanel.Children, 'Visible', false);
            set(app.AutrePanel.Children, 'Visible', false);
            set(app.ActionneurlineairePanel.Children, 'Visible', false);
            set(app.AmplificateurPanel.Children, 'Visible', false);
        end
    end
    
    methods (Access = private)
        
        function enableUI(app)
            set(app.UICoinTable, 'ColumnEditable', true);
            set(app.DimensionsdelabalancePanel, 'Visible', true);
            set(app.LameflexiblePanel.Children, 'Visible', true);
            set(app.CapteurcapacitifPanel.Children, 'Visible', true);
            set(app.AutrePanel.Children, 'Visible', true);
            set(app.ActionneurlineairePanel.Children, 'Visible', true);
            set(app.AmplificateurPanel.Children, 'Visible', true);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add untracked paths
            addpath('plotFunc');
            addpath('UIs');
            
            % Initially plot the base of the scale
            plotBase(app.UIAnim,app.ScaleWidth,app.ScaleHeight,zeros(1,15),app.LameWidth);
            close all % Close remaining figures that pop-up for no reason
        end

        % Callback function
        function AddButtonPushed(app, event)
            % Set UI to grey (no changes)
%             set(app.SimulationTab.Children, 'Enable', 'off');
%             set(app.ConfigurationTab.Children, 'Enable', 'off');
%             set(app.ArchiveTab.Children, 'Enable', 'off');
            
            % Open pop-up
            AddPopUp = addpopup();
            
            % Waite until pop-up closes
            uiwait(AddPopUp.UIFigure);
            
            % Reset UI to original state
%             set(app.SimulationTab.Children, 'Enable', 'on');
%             set(app.ConfigurationTab.Children, 'Enable', 'on');
%             set(app.ArchiveTab.Children, 'Enable', 'on');
        end

        % Button pushed function: SimulerButton
        function SimulerButtonPushed(app, event)
            if app.SimulerButton.Text == "Stop"
                app.Lamp.Color = 'green';
                app.SimulerButton.Text = "Simuler";
                enableUI(app);
            elseif app.SimulerButton.Text == "Simuler"
                app.Lamp.Color = 'red';
                app.SimulerButton.Text = "Stop";
                disableUI(app);
%                 ret = simuation(app,app.SystemStruct);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulateurdebalanceUIFigure = uifigure('Visible', 'off');
            app.SimulateurdebalanceUIFigure.Name = 'Simulateur de balance';
            app.TabGroup = uitabgroup(app.SimulateurdebalanceUIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAnim = uiaxes(app.SimulationTab);
            title(app.UIAnim, 'Animation temps réel')
            xlabel(app.UIAnim, '')
            ylabel(app.UIAnim, '')
            app.UIAnim.XTick = [];
            app.UIAnim.XTickLabel = '';
            app.UIAnim.YTick = [];
            app.Lamp = uilamp(app.SimulationTab);
            app.ParamtresnumismatiqueLabel = uilabel(app.SimulationTab);
            app.ParamtresnumismatiqueLabel.Text = 'Paramètres numismatique';
            app.UICoinTable = uitable(app.SimulationTab);
            app.UICoinTable.ColumnName = {'Devise'; 'Quantité'; 'Montant ($)'};
            app.UICoinTable.RowName = {};
            app.UICoinTable.ColumnEditable = true;
            app.UIMassAction = uiaxes(app.SimulationTab);
            title(app.UIMassAction, 'Affichage de la masse')
            xlabel(app.UIMassAction, 'X')
            ylabel(app.UIMassAction, 'Y')
            app.SimulerButton = uibutton(app.SimulationTab, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton.Text = 'Simuler';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.DimensionsdelabalancePanel = uipanel(app.ConfigurationTab);
            app.DimensionsdelabalancePanel.Title = 'Dimensions de la balance';
            app.LongueurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.LongueurcmEditFieldLabel.Text = 'Longueur (cm)';
            app.LongueurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.LargeurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.LargeurcmEditFieldLabel.Text = 'Largeur (cm)';
            app.LargeurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.HauteurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.HauteurcmEditFieldLabel.Text = 'Hauteur (cm)';
            app.HauteurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.UIDimension = uiaxes(app.DimensionsdelabalancePanel);
            title(app.UIDimension, '')
            xlabel(app.UIDimension, '')
            ylabel(app.UIDimension, '')
            app.UIDimension.XTick = [];
            app.UIDimension.XTickLabel = '';
            app.UIDimension.YTick = [];
            app.PositionduplateauSliderLabel = uilabel(app.DimensionsdelabalancePanel);
            app.PositionduplateauSliderLabel.Text = 'Position du plateau';
            app.PositionduplateauSlider = uislider(app.DimensionsdelabalancePanel);
            app.LameflexiblePanel = uipanel(app.ConfigurationTab);
            app.LameflexiblePanel.Title = 'Lame flexible';
            app.LongueurcmEditField_2Label = uilabel(app.LameflexiblePanel);
            app.LongueurcmEditField_2Label.Text = 'Longueur (cm)';
            app.LongueurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.LargeurcmEditField_2Label = uilabel(app.LameflexiblePanel);
            app.LargeurcmEditField_2Label.Text = 'Largeur (cm)';
            app.LargeurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.paisseurcmEditFieldLabel = uilabel(app.LameflexiblePanel);
            app.paisseurcmEditFieldLabel.Text = 'Épaisseur (cm)';
            app.EpaisseurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.MassegEditFieldLabel = uilabel(app.LameflexiblePanel);
            app.MassegEditFieldLabel.Text = 'Masse (g)';
            app.MasseLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.UIAxes = uiaxes(app.LameflexiblePanel);
            title(app.UIAxes, 'Dimensions de la lame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.ZTickLabel = {'0'; '0.5'; '1'};
            app.CapteurcapacitifPanel = uipanel(app.ConfigurationTab);
            app.CapteurcapacitifPanel.Title = 'Capteur capacitif';
            app.LongueurcmEditField_3Label = uilabel(app.CapteurcapacitifPanel);
            app.LongueurcmEditField_3Label.Text = 'Longueur (cm)';
            app.LongueurCaptField = uieditfield(app.CapteurcapacitifPanel, 'numeric');
            app.LargeurcmEditField_3Label = uilabel(app.CapteurcapacitifPanel);
            app.LargeurcmEditField_3Label.Text = 'Largeur (cm)';
            app.LargeurCaptField = uieditfield(app.CapteurcapacitifPanel, 'numeric');
            app.MatriauxDropDownLabel = uilabel(app.CapteurcapacitifPanel);
            app.MatriauxDropDownLabel.Text = 'Matériaux';
            app.MateriauxCaptDropDown = uidropdown(app.CapteurcapacitifPanel);
            app.MateriauxCaptDropDown.Items = {'Fer', 'Aliage..'};
            app.MateriauxCaptDropDown.Value = 'Fer';
            app.AutrePanel = uipanel(app.ConfigurationTab);
            app.AutrePanel.Title = 'Autre';
            app.AmplificateurPanel = uipanel(app.ConfigurationTab);
            app.AmplificateurPanel.Title = 'Amplificateur';
            app.ActionneurlineairePanel = uipanel(app.ConfigurationTab);
            app.ActionneurlineairePanel.Title = 'Actionneur linéaire';
            app.NombredespiresSpinnerLabel = uilabel(app.ActionneurlineairePanel);
            app.NombredespiresSpinnerLabel.Text = 'Nombre de spires';
            app.NombredespiresSpinner = uispinner(app.ActionneurlineairePanel);
            app.ArchiveTab = uitab(app.TabGroup);
            app.ArchiveTab.Title = 'Archive';
            % Show the figure after all components are created
            app.SimulateurdebalanceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainCAO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulateurdebalanceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulateurdebalanceUIFigure)
        end
    end
end